classdef FRApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlayFilteredSignalButton       matlab.ui.control.Button
        GenerateRawFrequencyResponseButton  matlab.ui.control.Button
        FilterSelectionPanel           matlab.ui.container.Panel
        F2EditField                    matlab.ui.control.NumericEditField
        F2EditFieldLabel               matlab.ui.control.Label
        F1EditField                    matlab.ui.control.NumericEditField
        F1EditFieldLabel               matlab.ui.control.Label
        FilterCutoffEditField          matlab.ui.control.NumericEditField
        FilterCutoffEditFieldLabel     matlab.ui.control.Label
        Order                          matlab.ui.control.Spinner
        OrderSpinnerLabel              matlab.ui.control.Label
        GenerateFilterResponsesButton  matlab.ui.control.Button
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        FIRIIRDropDown                 matlab.ui.control.DropDown
        FIRIIRDropDownLabel            matlab.ui.control.Label
        PreviewButton                  matlab.ui.control.Button
        SelectAudioFileDropDown        matlab.ui.control.DropDown
        SelectAudioFileLabel           matlab.ui.control.Label
        PZAxes                         matlab.ui.control.UIAxes
        ImpResp                        matlab.ui.control.UIAxes
        PhaseResp                      matlab.ui.control.UIAxes
        FreqResp                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AudioFile double = [];
        Fs = 0;
        freqax double = [];
        mag double = [];
        filtfreqax double = [];
        filt_mag double = [];
        filtsignal double = [];
        folderPath = './Audio Files/';
    end


    methods (Access = private)
        
        function initPoleZeroAxes(app)
            cla(app.PZAxes);
            theta = linspace(0, 2*pi);
            plot(app.PZAxes, cos(theta), sin(theta), 'k--'); % Unit circle
            hold(app.PZAxes, 'on');
            axis(app.PZAxes, 'equal');
            xlim(app.PZAxes, [-1.5 1.5]);
            ylim(app.PZAxes, [-1.5 1.5]);
            grid(app.PZAxes, 'on');
            title(app.PZAxes, 'Pole-Zero Plot');
            xlabel(app.PZAxes, 'Re(Z)');
            ylabel(app.PZAxes, 'Im(Z)');
        end
        
        function plotFreqResp(app)
            signal = app.AudioFile;
            fs = app.Fs;
        
            
            if size(signal, 2) > 1
                signal = mean(signal, 2);
            end
        
            N = length(signal);
            Y = fft(signal);
            Y = Y(1:floor(N/2)+1);  % One-sided spectrum
        
            f = linspace(0, fs/2, length(Y));
        
            mag = abs(Y);
            mag = mag / max(mag);  % Normalize magnitude
        
            % Plot
            cla(app.FreqResp);
            plot(app.FreqResp, f, mag, 'Color', [1 0.5 0]);
            app.FreqResp.XAxis.Exponent = 0;  
            xticks(app.FreqResp, linspace(0, app.Fs/2, 6));  
            xtickformat(app.FreqResp, '%,.0f');  
            app.FreqResp.XLim = [0 fs/2];
            app.FreqResp.XLimMode = 'manual';
            app.FreqResp.YLim = [0 1.1];
            title(app.FreqResp, 'Frequency Response');
            xlabel(app.FreqResp, 'Frequency (Hz)');
            ylabel(app.FreqResp, 'Magnitude');
            grid(app.FreqResp, 'on');
        
            % Store
            app.freqax = f;
            app.mag = mag;
        end
        
        function filterproc(app)
            iirorfirvalue = app.FIRIIRDropDown.Value;
            filtval = app.FilterTypeDropDown.Value;
            ord_val = app.Order.Value;

            y = app.AudioFile;
            if size(y,2) > 1
                y = mean(y, 2); 
            end

            fs = app.Fs;
            fc = app.FilterCutoffEditField.Value; 
            if isempty(fc) || ~isfinite(fc) || fc <= 0 || fc >= fs/2
                uialert(app.UIFigure, 'Please enter a valid cutoff frequency.', 'Invalid Input');
                app.filtsig = [];
                return;
            end

             
            if strcmp(iirorfirvalue, 'FIR')
                if strcmp(filtval, 'Low Pass')
                    app.FilterCutoffEditField.Visible = 'on';
                    
                    app.F1EditField.Visible = 'off';
                    app.F2EditField.Visible = 'off';
                    app.FilterCutoffEditField.Visible = 'off';
                    app.FilterCutoffEditFieldLabel.Visible = 'off';


                    b = fir1(ord_val, fc/(fs/2), 'low');

                    filt_sig = filter(b, 1, y);

                    
                elseif strcmp(filtval, 'High Pass')
                    app.FilterCutoffEditField.Visible = 'on';
                    app.F1EditField.Visible = 'off';
                    app.F2EditField.Visible = 'off';
                    app.FilterCutoffEditField.Visible = 'off';
                    app.FilterCutoffEditFieldLabel.Visible = 'off';


                    b = fir1(ord_val, fc/(fs/2), 'high');

                    filt_sig = filter(b, 1, y);

                    
                elseif strcmp(filtval, 'Band Pass')
                    app.FilterCutoffEditField.Visible = 'off';
                    app.F1EditField.Visible = 'on';
                    app.F2EditField.Visible = 'on';


                    f_low = app.F1EditField.Value;
                    f_high = app.F2EditField.Value;

                    Wn = [f_low f_high] / (fs/2);

                    b = fir1(ord_val, Wn, 'bandpass');

                    filt_sig = filter(b, 1, y);

                    
                elseif strcmp(filtval, 'Band Stop')
                    app.FilterCutoffEditField.Visible = 'off';
                    app.F1EditField.Visible = 'on';
                    app.F2EditField.Visible = 'on';


                    f_low = app.F1EditField.Value;
                    f_high = app.F2EditField.Value;

                    Wn = [f_low f_high] / (fs/2);

                    b = fir1(ord_val, Wn, 'stop');

                    filt_sig = filter(b, 1, y);

                    
                end
            elseif strcmp(iirorfirvalue, 'IIR')
                if strcmp(filtval, 'Low Pass')
                    app.FilterCutoffEditField.Visible = 'on';
                    app.F1EditField.Visible = 'off';
                    app.F2EditField.Visible = 'off';

                    [b, a] = butter(ord_val, fc/(fs/2), 'low');

                    filt_sig = filter(b, a, y);


                elseif strcmp(filtval, 'High Pass')
                    app.FilterCutoffEditField.Visible = 'on';
                    app.F1EditField.Visible = 'off';
                    app.F2EditField.Visible = 'off';

                    [b,a] = butter(ord_val, fc/(fs/2), 'high');

                    filt_sig = filter(b, a, y);


                elseif strcmp(filtval, 'Band Pass')
                    app.FilterCutoffEditField.Visible = 'off';
                    app.F1EditField.Visible = 'on';
                    app.F2EditField.Visible = 'on';

                    f_low = app.F1EditField.Value;
                    f_high = app.F2EditField.Value;

                    Wn = [f_low f_high] / (fs/2);

                    [b, a] = butter(ord_val, Wn, 'bandpass');

                    filt_sig = filter(b, a, y);


                elseif strcmp(filtval, 'Band Stop')
                    app.FilterCutoffEditField.Visible = 'off';
                    app.F1EditField.Visible = 'on';
                    app.F2EditField.Visible = 'on';

                    f_low = app.F1EditField.Value;
                    f_high = app.F2EditField.Value;

                    Wn = [f_low f_high] / (fs/2);

                    [b, a] = butter(ord_val, Wn, 'stop');

                    filt_sig = filter(b, a, y);

                    
                elseif strcmp(filtval, 'All Pass')
                    app.FilterCutoffEditField.Visible = 'off';
                    app.F1EditField.Visible = 'off';
                    app.F2EditField.Visible = 'off';

                    alpha = 0.7;
                    filt_sig = y;

                    for i = 1:ord_val
                        b = [alpha, 1];
                        a = [1, alpha];
                        filt_sig = filter(b, a, filt_sig);
                    end
                end


            end

            app.filtsignal = filt_sig;
            
        end
        
        function plotFiltFR(app)
            filterproc(app);
            signal = app.filtsignal;
            fs = app.Fs;
        
            
            if size(signal, 2) > 1
                signal = mean(signal, 2);
            end
        
            N = length(signal);
            Y = fft(signal);
            Y = Y(1:floor(N/2)+1);  
        
            f = linspace(0, fs/2, length(Y));
        
            mag = abs(Y);
            

            mag = 20 * log10(abs(Y) / max(abs(Y)));
        
            f(1) = 1e-1;
            cla(app.FreqResp);
            semilogx(app.FreqResp, f, mag, 'Color', [1 0 0]);
            app.FreqResp.XAxis.Exponent = 0;  
            xticks(app.FreqResp, [10 100 1e3 10e3 fs/2]); 
            xtickformat(app.FreqResp, '%,.0f');  
            app.FreqResp.XLim = [0 fs/2];
            app.FreqResp.XLimMode = 'manual';
            app.FreqResp.YLim = [-80 5];
            title(app.FreqResp, 'Frequency Response Plot');
            xlabel(app.FreqResp, 'Frequency (Hz)');
            ylabel(app.FreqResp, 'Magnitude (dB)');
            grid(app.FreqResp, 'on');
        
            
            app.freqax = f;
            app.mag = mag;
            
        end
        
        function plotfiltPhase(app)
            filterproc(app);
            signal = app.filtsignal;
            fs = app.Fs;
            filtval = app.FilterTypeDropDown.Value;
        
            N = length(signal);
            Y = fft(signal);
            Y = Y(1:floor(N/2)+1); 
        
            f = linspace(0, fs/2, length(Y));
            phase = angle(Y);  
        
            % Plot
            cla(app.PhaseResp);  
            plot(app.PhaseResp, f, unwrap(phase), 'Color', [1 0 0]);
            app.PhaseResp.XAxis.Exponent = 0;
            xticks(app.PhaseResp, linspace(0, fs/2, 6));
            xtickformat(app.PhaseResp, '%,.0f');
            app.PhaseResp.XLim = [0 fs/2];
            app.PhaseResp.XLimMode = 'manual';
            title(app.PhaseResp, 'Phase Response Plot');
            xlabel(app.PhaseResp, 'Frequency (Hz)');
            ylabel(app.PhaseResp, 'Phase (rad)');
            grid(app.PhaseResp, 'on');
            
        end
        
        function zeroPlot(app)
            filterproc(app);
            filtval = app.FilterTypeDropDown.Value;
        
           
            iirorfir = app.FIRIIRDropDown.Value;
            fs = app.Fs;
        
            
            ord = app.Order.Value;
            y = app.AudioFile;
            if size(y, 2) > 1
                y = mean(y, 2);
            end


            fc = app.FilterCutoffEditField.Value;
        
            if strcmp(iirorfir, 'IIR')
                if strcmp(filtval, 'Low Pass')
                    [b, a] = butter(ord, fc/(fs/2), 'low');
                elseif strcmp(filtval, 'High Pass')
                    [b, a] = butter(ord, fc/(fs/2), 'high');
                elseif strcmp(filtval, 'Band Pass')
                    f1 = app.F1EditField.Value;
                    f2 = app.F2EditField.Value;
                    Wn = [f1 f2] / (fs/2);
                    [b, a] = butter(ord, Wn, 'bandpass');
                elseif strcmp(filtval, 'Band Stop')
                    f1 = app.F1EditField.Value;
                    f2 = app.F2EditField.Value;
                    Wn = [f1 f2] / (fs/2);
                    [b, a] = butter(ord, Wn, 'stop');
                elseif strcmp(filtval, 'All Pass')
                    alpha = 0.7;
                    b = [alpha, 1];
                    a = [1, alpha];
                end
            elseif strcmp(iirorfir, 'FIR')
                if strcmp(filtval, 'Low Pass')
                    b = fir1(ord, fc/(fs/2), 'low'); a = 1;
                elseif strcmp(filtval, 'High Pass')
                    b = fir1(ord, fc/(fs/2), 'high'); a = 1;
                elseif strcmp(filtval, 'Band Pass')
                    f1 = app.F1EditField.Value;
                    f2 = app.F2EditField.Value;
                    Wn = [f1 f2] / (fs/2);
                    b = fir1(ord, Wn, 'bandpass'); a = 1;
                elseif strcmp(filtval, 'Band Stop')
                    f1 = app.F1EditField.Value;
                    f2 = app.F2EditField.Value;
                    Wn = [f1 f2] / (fs/2);
                    b = fir1(ord, Wn, 'stop'); a = 1;
                end
            end
        
            
            z = roots(b);
            p = roots(a);
        
            
            cla(app.PZAxes);
            theta = linspace(0, 2*pi, 500);
            plot(app.PZAxes, cos(theta), sin(theta), 'k--'); % Unit circle
            hold(app.PZAxes, 'on');
            plot(app.PZAxes, real(z), imag(z), 'ob', 'LineWidth', 1.5); % Zeros
            plot(app.PZAxes, real(p), imag(p), 'xr', 'LineWidth', 1.5); % Poles
            axis(app.PZAxes, 'equal');
            xlim(app.PZAxes, [-1.5 1.5]);
            ylim(app.PZAxes, [-1.5 1.5]);
            title(app.PZAxes, 'Pole-Zero Plot');
            xlabel(app.PZAxes, 'Re');
            ylabel(app.PZAxes, 'Im');
            grid(app.PZAxes, 'on');
        end


        function plotImpR(app)
            filterproc(app);
            signal = app.filtsignal;
            fs = app.Fs;

            if size(signal, 2) > 1
                signal = mean(signal, 2, 'yellow');
            end
        

            time_in_seconds = length(signal)/fs;

            time_ax = linspace(0, time_in_seconds, length(signal));
            disp(time_ax);

            plot(app.ImpResp, time_ax, signal);
            ylim(app.ImpResp, [0 max(signal)]);
            title(app.ImpResp, 'Impulse Response Plot');
            xlabel(app.ImpResp, 'Time (s)');
            xlim(app.ImpResp, [0 1]);
            ylabel(app.ImpResp, 'Magnitude');  

            grid(app.ImpResp, 'on');


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initPoleZeroAxes(app);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            sound(app.AudioFile, app.Fs);
        end

        % Value changed function: SelectAudioFileDropDown
        function SelectAudioFileDropDownValueChanged(app, event)
            value = app.SelectAudioFileDropDown.Value;
            %folderPath = './Audio Files/';
            if strcmp(value, 'Impulse')
                clear sound;
                [y, fs] = audioread(fullfile(app.folderPath, 'Impulse.wav'));
                app.AudioFile = y;
                app.Fs = fs;
            elseif strcmp(value, 'SineSweep')
                clear sound;
                [y, fs] = audioread(fullfile(app.folderPath, 'SineSweep.wav'));
                app.AudioFile = y;
                app.Fs = fs;
            elseif strcmp(value, 'WhiteNoise')
                clear sound;
                [y, fs] = audioread(fullfile(app.folderPath, 'WhiteNoise.wav'));
                app.AudioFile = y;
                app.Fs = fs;
            end
        
        end

        % Button pushed function: GenerateRawFrequencyResponseButton
        function GenerateRawFrequencyResponseButtonPushed(app, event)
            plotFreqResp(app);
            
        end

        % Button pushed function: GenerateFilterResponsesButton
        function GenerateFilterResponsesButtonPushed(app, event)
            
            try
                filterproc(app); % this runs first and validates input
            catch
                return; % if filterproc throws or fails, exit early
            end
            
            % Proceed only if the signal is valid
            if isempty(app.filtsignal)
                return;
            end
            
            plotFiltFR(app);
            plotfiltPhase(app);
            zeroPlot(app);
            plotImpR(app);
        end

        % Value changed function: FIRIIRDropDown
        function FIRIIRDropDownValueChanged(app, event)
            value = app.FIRIIRDropDown.Value;
            
            if strcmp(value, 'IIR')
                app.FilterTypeDropDown.Items = {'-', 'Low Pass', 'High Pass', 'Band Pass', 'Band Stop', 'All Pass'};
                app.Order.Limits = [2 20];
                app.FilterCutoffEditField.Visible = 'off';
                app.FilterCutoffEditFieldLabel.Visible = 'off';

                app.F1EditField.Visible = 'off';
                app.F1EditFieldLabel.Visible = 'off';

                app.F2EditField.Visible = 'off';
                app.F2EditFieldLabel.Visible = 'off';
                app.FilterTypeDropDown.Value = '-';
            elseif strcmp(value, 'FIR')
                app.FilterTypeDropDown.Items = {'-', 'Low Pass', 'High Pass', 'Band Pass', 'Band Stop'};
                app.Order.Step = 2;
                app.Order.Limits = [21 299]; % odd range within FIR recommended values
                app.Order.Value = 21; 
                
                app.FilterCutoffEditField.Visible = 'off';
                app.FilterCutoffEditFieldLabel.Visible = 'off';

                app.F1EditField.Visible = 'off';
                app.F1EditFieldLabel.Visible = 'off';

                app.F2EditField.Visible = 'off';
                app.F2EditFieldLabel.Visible = 'off';
                app.FilterTypeDropDown.Value = '-';
            else
                app.FilterTypeDropDown.Items = {'-'};
                app.FilterCutoffEditField.Visible = 'off';
                app.FilterCutoffEditFieldLabel.Visible = 'off';

                app.F1EditField.Visible = 'off';
                app.F1EditFieldLabel.Visible = 'off';

                app.F2EditField.Visible = 'off';
                app.F2EditFieldLabel.Visible = 'off';
                app.FilterTypeDropDown.Value = '-';
            end


        end

        % Button pushed function: PlayFilteredSignalButton
        function PlayFilteredSignalButtonPushed(app, event)
            filterproc(app);
            sound(app.filtsignal, app.Fs);
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            
            if strcmp(value, 'Low Pass') || strcmp(value, 'High Pass')
                app.FilterCutoffEditField.Visible = 'on';
                app.FilterCutoffEditFieldLabel.Visible = 'on';

                app.F1EditField.Visible = 'off';
                app.F1EditFieldLabel.Visible = 'off';

                app.F2EditField.Visible = 'off';
                app.F2EditFieldLabel.Visible = 'off';

            elseif strcmp(value, 'Band Pass') || strcmp(value, 'Band Stop') 
                app.FilterCutoffEditField.Visible = 'off';
                app.FilterCutoffEditFieldLabel.Visible = 'off';

                app.F1EditField.Visible = 'on';
                app.F1EditFieldLabel.Visible = 'on';

                app.F2EditField.Visible = 'on';
                app.F2EditFieldLabel.Visible = 'on';
            elseif strcmp(value, 'All Pass')
                app.FilterCutoffEditField.Visible = 'off';
                app.FilterCutoffEditFieldLabel.Visible = 'off';

                app.F1EditField.Visible = 'off';
                app.F1EditFieldLabel.Visible = 'off';

                app.F2EditField.Visible = 'off';
                app.F2EditFieldLabel.Visible = 'off';
            else
                app.FilterCutoffEditField.Visible = 'off';
                app.FilterCutoffEditFieldLabel.Visible = 'off';

                app.F1EditField.Visible = 'off';
                app.F1EditFieldLabel.Visible = 'off';

                app.F2EditField.Visible = 'off';
                app.F2EditFieldLabel.Visible = 'off';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FreqResp = uiaxes(app.UIFigure);
            title(app.FreqResp, 'Frequency Response')
            xlabel(app.FreqResp, 'Frequency (Hz)')
            ylabel(app.FreqResp, 'Magnitude')
            zlabel(app.FreqResp, 'Z')
            app.PhaseResp = uiaxes(app.UIFigure);
            title(app.PhaseResp, 'Phase Response')
            xlabel(app.PhaseResp, 'X')
            ylabel(app.PhaseResp, 'Y')
            zlabel(app.PhaseResp, 'Z')
            app.ImpResp = uiaxes(app.UIFigure);
            title(app.ImpResp, 'Impulse Response')
            xlabel(app.ImpResp, 'X')
            ylabel(app.ImpResp, 'Y')
            zlabel(app.ImpResp, 'Z')
            app.PZAxes = uiaxes(app.UIFigure);
            title(app.PZAxes, 'Pole-Zero Plot')
            xlabel(app.PZAxes, 'X')
            ylabel(app.PZAxes, 'Y')
            zlabel(app.PZAxes, 'Z')
            app.SelectAudioFileLabel = uilabel(app.UIFigure);
            app.SelectAudioFileLabel.Text = 'Select Audio File:';
            app.SelectAudioFileDropDown = uidropdown(app.UIFigure);
            app.SelectAudioFileDropDown.Items = {'-', 'Impulse', 'SineSweep', 'WhiteNoise'};
            app.SelectAudioFileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAudioFileDropDownValueChanged, true);
            app.SelectAudioFileDropDown.Value = '-';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.FilterSelectionPanel = uipanel(app.UIFigure);
            app.FilterSelectionPanel.Title = 'Filter Selection';
            app.FIRIIRDropDownLabel = uilabel(app.FilterSelectionPanel);
            app.FIRIIRDropDownLabel.Text = 'FIR / IIR';
            app.FIRIIRDropDown = uidropdown(app.FilterSelectionPanel);
            app.FIRIIRDropDown.Items = {'-', 'FIR', 'IIR'};
            app.FIRIIRDropDown.ValueChangedFcn = createCallbackFcn(app, @FIRIIRDropDownValueChanged, true);
            app.FIRIIRDropDown.Value = '-';
            app.FilterTypeDropDownLabel = uilabel(app.FilterSelectionPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilterSelectionPanel);
            app.FilterTypeDropDown.Items = {'-', 'Low Pass', 'High Pass', 'Band Pass', 'Band Stop', 'All Pass'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = '-';
            app.GenerateFilterResponsesButton = uibutton(app.FilterSelectionPanel, 'push');
            app.GenerateFilterResponsesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFilterResponsesButtonPushed, true);
            app.GenerateFilterResponsesButton.Text = 'Generate Filter Responses';
            app.OrderSpinnerLabel = uilabel(app.FilterSelectionPanel);
            app.OrderSpinnerLabel.Text = 'Order';
            app.Order = uispinner(app.FilterSelectionPanel);
            app.FilterCutoffEditFieldLabel = uilabel(app.FilterSelectionPanel);
            app.FilterCutoffEditFieldLabel.Visible = 'off';
            app.FilterCutoffEditFieldLabel.Text = 'Filter Cutoff';
            app.FilterCutoffEditField = uieditfield(app.FilterSelectionPanel, 'numeric');
            app.FilterCutoffEditField.Visible = 'off';
            app.F1EditFieldLabel = uilabel(app.FilterSelectionPanel);
            app.F1EditFieldLabel.Visible = 'off';
            app.F1EditFieldLabel.Text = 'F1';
            app.F1EditField = uieditfield(app.FilterSelectionPanel, 'numeric');
            app.F1EditField.Visible = 'off';
            app.F2EditFieldLabel = uilabel(app.FilterSelectionPanel);
            app.F2EditFieldLabel.Visible = 'off';
            app.F2EditFieldLabel.Text = 'F2';
            app.F2EditField = uieditfield(app.FilterSelectionPanel, 'numeric');
            app.F2EditField.Visible = 'off';
            app.GenerateRawFrequencyResponseButton = uibutton(app.UIFigure, 'push');
            app.GenerateRawFrequencyResponseButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRawFrequencyResponseButtonPushed, true);
            app.GenerateRawFrequencyResponseButton.Text = 'Generate Raw Frequency Response';
            app.PlayFilteredSignalButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredSignalButtonPushed, true);
            app.PlayFilteredSignalButton.Text = 'Play Filtered Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FRApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end